classdef DataLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChannelsPanel                  matlab.ui.container.Panel
        chanName_1                     matlab.ui.control.EditField
        chanOn_8                       matlab.ui.control.Switch
        chanOn_7                       matlab.ui.control.Switch
        chanOn_6                       matlab.ui.control.Switch
        chanOn_5                       matlab.ui.control.Switch
        chanOn_4                       matlab.ui.control.Switch
        chanOn_3                       matlab.ui.control.Switch
        chanOn_2                       matlab.ui.control.Switch
        chanOn_1                       matlab.ui.control.Switch
        EnableLabel                    matlab.ui.control.Label
        ChannelNameLabel               matlab.ui.control.Label
        chanName_8                     matlab.ui.control.EditField
        chanName_7                     matlab.ui.control.EditField
        chanName_6                     matlab.ui.control.EditField
        chanName_5                     matlab.ui.control.EditField
        chanName_4                     matlab.ui.control.EditField
        chanName_3                     matlab.ui.control.EditField
        chanName_2                     matlab.ui.control.EditField
        RateControlsPanel              matlab.ui.container.Panel
        PlotRateHzEditField            matlab.ui.control.NumericEditField
        PlotRateHzEditFieldLabel       matlab.ui.control.Label
        DAQRateHzEditField             matlab.ui.control.NumericEditField
        DAQRateHzEditFieldLabel        matlab.ui.control.Label
        DAQLoggingControlsPanel        matlab.ui.container.Panel
        EndLoggingDataButton           matlab.ui.control.StateButton
        ArmDataAcquisitionSwitch       matlab.ui.control.Switch
        ArmDataAcquisitionSwitchLabel  matlab.ui.control.Label
        BeginLoggingDataButton         matlab.ui.control.StateButton
        FileSavingParametersPanel      matlab.ui.container.Panel
        FolderNote                     matlab.ui.control.Label
        FolderButton                   matlab.ui.control.Button
        ProjectFolderEditField         matlab.ui.control.EditField
        ProjectFolderEditFieldLabel    matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        mainPlot                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        s1      % DAQ session for temp, volktage in/out
%         s2      % DAQ session for SG
%         output  % Voltages to output
        data    % Input data matrix
        name    % Filenames and folders
        FID     % fopen fileID
        saveForm %fprintf save format, must update if new inputs added
        rate    % All relevant information for timing/rates and rate reduction
        indx     % Relevant indices to store
        plotTimer
        saveTimer
        lh1_in   % Listener for input
%         lh1_out  % Listener for Output
%         lh2_in  % SG input listener
    end

    methods (Access = private)
    
        function GrabData1(app,event,info)
            %Append matrices with new data
            app.data.time = [app.data.time;info.TimeStamps];
            app.data.temp = [app.data.temp;info.Data(:,1)];
        end
    
%         function GrabData2(app,event,info)
%             %Grab first datapoint
%             if isempty(app.data.sg)
%                 app.data.sgtime = info.TimeStamps(1);
%                 app.data.sg = info.Data(1,1);
%                 app.data.pos = 36*info.Data(1,2:3);
%                 app.data.posVolts = info.Data(1,2:3);
%             end
%             %If you can, downsample data
%             if length(info.TimeStamps(1:app.rate.reduce:end)) == length(info.Data(1:app.rate.reduce:end,1))
%                 app.data.sgtime0 = [app.data.sgtime0;info.TimeStamps(1:app.rate.reduce:end)];
%                 app.data.sg0 = [app.data.sg0;info.Data(1:app.rate.reduce:end,1)];
%                 app.data.pos0 = [app.data.pos0; info.Data(1:app.rate.reduce:end,2),info.Data(1:app.rate.reduce:end,3)];
%             %Otherwise just grab all of it
%             else
%                 app.data.sgtime0 = [app.data.sgtime0;info.TimeStamps];
%                 app.data.sg0 = [app.data.sg0;info.Data(:,1)];
%                 app.data.pos0 = [app.data.pos0;info.Data(:,2:3)];
%             end
%         end
        
%         function SendData1(app,event,handles)
%             if isempty(app.data.time)
%                 app.output = [10*ones(app.DAQRateHzEditField.Value,2),zeros(app.DAQRateHzEditField.Value,1)];
%             else
%                 app.output = [10*ones(app.DAQRateHzEditField.Value,2),app.data.posVolts(end,1)*ones(app.DAQRateHzEditField.Value,1)];
%             end
%             app.s1.queueOutputData(app.output);
%         end
        
    
        function plotTimerUpdateFcn(app,src,event)
            %Update Plots
            if ~isempty(app.data.time) && ~isempty(app.data.sgtime0)
                plot(app.mainPlot,app.data.time,app.data.temp)
                if length(app.data.sgtime0) == length(app.data.sg0) &&  length(app.data.sgtime0) == length(app.data.pos0)
                    plot(app.mainPlot,app.data.sgtime0,36*app.data.pos0)
%                     plot(app.torqPlot,app.data.sgtime0,app.data.sg0)
                end
            end
        end
    
        function saveTimerUpdateFcn(app,src,event)
            %Pull DAQ2 data that aligns with timestamps from DAQ1
            if length(app.data.sgtime) < length(app.data.time)
                for i = (length(app.data.sg)+1):length(app.data.time)
                    [~,ind] = min(abs(app.data.sgtime0(app.indx.sg:end) - app.data.time(i)));
                    app.data.sgtime = [app.data.sgtime; app.data.sgtime0(app.indx.sg+ind)];
                    app.data.sg = [app.data.sg; app.data.sg0(app.indx.sg+ind)];
                    app.data.pos = [app.data.pos; 36*app.data.pos0(app.indx.sg+ind,:)];
%                     app.data.posVolts = [app.data.posVolts; app.data.pos0(app.indx.sg+ind,:)];
                end
                %Note the starting index of your next pull
                app.indx.sg = app.indx.sg + ind;
            end
            
            %Save Data into file
            if app.indx.save < length(app.data.time)
                %%%%%%%% ----------- NEEDS CHANGING
                savebatch = [app.data.time(app.indx.save:end),app.data.temp(app.indx.save:end,:),app.data.pos(app.indx.save:end,:),...
                    app.data.posVolts(app.indx.save:end,:),app.data.sgtime(app.indx.save:end),app.data.sg(app.indx.save:end,:)];
                for i = 1:length(app.data.time(app.indx.save:end))
                    fprintf(app.FID,app.saveForm,savebatch(i,:));
                end
                app.indx.save = length(app.data.time)+1;
            end
        end


        function getChannelActiveState(app)
            app.chanOn_1
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % For now nothing...
            
        end

        % Button pushed function: FolderButton
        function FolderSelect(app, event)
            % Let the user select the folder to store data
            dialogTitle = 'Select directory for saving data';
            folderName = uigetdir('F:\Leo2018Summer\DAQLogger\',dialogTitle);
            
            app.ProjectFolderEditField.Value = folderName;
            
            % Enable DAQ arming
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.FolderNote.Visible = 'Off';
        end

        % Value changed function: BeginLoggingDataButton
        function StartLoggingSession(app, event)
            value = app.BeginLoggingDataButton.Value;
            if value
                %Create Data Saving timer
                app.saveTimer = timer('TimerFcn',{@app.saveTimerUpdateFcn},...
                    'ExecutionMode','fixedRate','busymode','queue',...
                    'Period',1/app.rate.save,'Name','timer-2');
                start(app.saveTimer);

                %Disable file selectors, BeginLoggingButton on GUI
                app.BeginLoggingDataButton.Enable = 'Off';
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                %Make visible and enable data logging shutoff button
                app.EndLoggingDataButton.Visible = 'On';
                app.EndLoggingDataButton.Enable = 'On';

            end
        end

        % Value changed function: ArmDataAcquisitionSwitch
        function ArmDataAcquisitionSwitchValueChanged(app, event)
            value = app.ArmDataAcquisitionSwitch.Value;
            
            %Actions to do when data acquisition has been armed
            if strcmp(value,'Armed')
                % Start up and preallocate DAQ 
                daqreset;
                %Declare Rates 
%                 app.rate.sgDAQ = ((12.8e6/256)/31); %(Hz) 
                app.rate.DAQ = app.DAQRateHzEditField.Value; %(Hz)
                app.rate.plot = app.PlotRateHzEditField.Value; %(Hz)
                app.rate.save = 0.5; %(Hz)
                %Predeclare data
                app.data.time = [];
                app.data.temp = [];
                app.data.pos = [];
                app.data.posVolts = [];
                app.data.pos0 = [];
                app.data.sgtime0 = [];
                app.data.sg0 = [];
                app.data.sgtime = [];
                app.data.sg = [];
                %Predeclare indices
                app.indx.save = 1;
                %Predeclare save format
                app.saveForm = '\n %f \t%f \t%f \t%f \t%f \t%f \t%f \t%f';
                
                %Disable data acquisition from being disarmed, change looks
                app.ArmDataAcquisitionSwitch.Enable = 'Off';
                app.ArmDataAcquisitionSwitch.FontWeight = 'bold';
                app.ArmDataAcquisitionSwitch.FontColor = [0.635,0.078,0.184];


                %Make folder in chosen directory for today's date (if not already there)
                app.name.projFolder = app.ProjectFolderEditField.Value;
                app.name.path = strcat(app.name.projFolder,'\',datestr(now,'yyyy_mm_dd'));
                if ~exist(app.name.path)
                    mkdir(app.name.projFolder,datestr(now,'yyyy_mm_dd'))
                end

                %Initialize filename of this test
                if ~isempty(app.FilenameEditField.Value)
                    app.name.filename = strcat(app.FilenameEditField.Value,'.txt');
                else
                    app.name.filename = strcat('testWithNoName',datestr(now,'mm_dd_yy_HH_MM_SS'),'.txt');
                end
                
                %Write header for file
                app.FID = fopen(strcat(app.name.path,'\',app.name.filename),'at+');
                fprintf(app.FID,'\n\n%s\n','DAQ Logger App (v2018)');
                fprintf(app.FID,'%s\n',['Date: ',datestr(now,'mm/dd/yyyy')]);
                fprintf(app.FID,'%s\n',['Start Time: ',datestr(now,'HH:MM:SS')]);
                fprintf(app.FID,'%s \t%s \t%s \t%s \t%s \t%s \t%s \t%s',...
                    'DAQ1 Time (s)','TC1 (C)','POT1 (deg)','POT2 (deg)','POT1 (V)','POT2 (V)','DAQ2 Time (s)','SG1');


                %CREATE DAQ SESSION 1 (Temp and Voltage ins/outs)
                app.s1 = daq.createSession('ni');
%                 app.rate.DAQ = app.DAQRateHzEditField.Value;
                app.s1.Rate = app.rate.DAQ;
                %Thermocouples
                app.s1.addAnalogInputChannel('cDAQ2Mod1',0,'Thermocouple');
                app.s1.Channels(1).ThermocoupleType = 'K';
                %Voltage OUT
                app.s1.addAnalogOutputChannel('cDAQ2Mod3','ao0','Voltage');
                app.s1.addAnalogOutputChannel('cDAQ2Mod3','ao1','Voltage');
                app.s1.addAnalogOutputChannel('cDAQ2Mod3','ao2','Voltage');
                app.output = [10*ones(app.DAQRateHzEditField.Value,2),zeros(app.DAQRateHzEditField.Value,1)];
                queueOutputData(app.s1,app.output);
                
                app.s1.IsContinuous = true;
                %Create input/output listeners
                app.lh1_in = app.s1.addlistener('DataAvailable',@app.GrabData1);
                app.lh1_out = app.s1.addlistener('DataRequired',@app.SendData1);


                %CREATE DAQ SESSION 2 (strain gauges)
                app.s2 = daq.createSession('ni');
                %Set rate, interval of DataAvailable
                app.s2.Rate = app.rate.sgDAQ;
                app.s2.NotifyWhenDataAvailableExceeds = floor( app.rate.sgDAQ/15 );
                %Strain Gauges
                app.s2.addAnalogInputChannel('cDAQ2Mod2','ai0','bridge');
                app.s2.Channels(1).BridgeMode = 'Full';
                app.s2.Channels(1).NominalBridgeResistance = 350;
                app.s2.Channels(1).ExcitationVoltage = 2.5;
                %Voltage IN
                app.s2.addAnalogInputChannel('cDAQ2Mod4',1:2,'Voltage');
                
                app.s2.IsContinuous = true;
                %Create input listener
                app.lh2_in = app.s2.addlistener('DataAvailable',@app.GrabData2);


                %Create Plotting Timer
                app.rate.plot = app.PlotRateHzEditField.Value;
                app.plotTimer = timer('TimerFcn',{@app.plotTimerUpdateFcn},...
                    'ExecutionMode','fixedRate','busymode','queue',...
                    'Period',1/app.rate.plot,'Name','timer-1');
                start(app.plotTimer);
                
                % Make rate and channel fields inative
                app.DAQRateHzEditField.Enable = 'Off';
                app.PlotRateHzEditField.Enable = 'Off';
                app.chanOn_1.Enable = 'Off';
                app.chanOn_2.Enable = 'Off';
                app.chanOn_3.Enable = 'Off';
                app.chanOn_4.Enable = 'Off';
                app.chanOn_5.Enable = 'Off';
                app.chanOn_6.Enable = 'Off';
                app.chanOn_7.Enable = 'Off';
                app.chanOn_8.Enable = 'Off';


                %Configure DAQ1 session as master, export trigger signal
                addTriggerConnection(app.s1,'cDAQ2/PFI0','External','StartTrigger');
                %Configure DAQ2 session as slave, to start acquisition when trigger is recieved
                addTriggerConnection(app.s2,'External','cDAQ2/PFI0','StartTrigger');
                
                
                %Start slave session waiting for trigger, then start master session
                startBackground(app.s2)
                while ~app.s2.IsWaitingForExternalTrigger
                    pause(0.1)
                end
                startBackground(app.s1)
                
            end
            
            
            %Enable data logging if data acq. has been armed and folder specified
            if ~isempty(app.ProjectFolderEditField.Value) && strcmp(value,'Armed')
                app.BeginLoggingDataButton.Enable = 'On';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.output = 0.*app.output;
            
            pause(1)
            
            %Stop sessions, catch errors in case they didn't exist or were already killed
            try
                stop(app.s1)
                stop(app.s2)
            catch
            end
            %Delete Listeners
            delete(app.lh1_in)
            delete(app.lh1_out)
            delete(app.lh2_in)
            %Stop timers
            if strcmp(get(app.plotTimer,'Running'),'on')
                stop(app.plotTimer)
            end
            if strcmp(get(app.saveTimer,'Running'),'on')
                stop(app.saveTimer)
            end
            %Close files
            fclose('all');
            
            delete(app)
        end

        % Value changed function: EndLoggingDataButton
        function EndLoggingDataButtonValueChanged(app, event)
            value = app.EndLoggingDataButton.Value;
            %If stop data logging button pressed:
            if value
                %Delete listeners
                delete(app.lh1_in)
                delete(app.lh1_out)
                delete(app.lh2_in)
                %Stop sessions
                stop(app.s1)
                stop(app.s2)
                %Stop Timers
                if strcmp(get(app.plotTimer,'Running'),'on')
                    stop(app.plotTimer)
                end
                if strcmp(get(app.saveTimer,'Running'),'on')
                    stop(app.saveTimer)
                end
                
                
                %Add final data to file, write footer and close
                if app.indx.save > length(app.data.time)
                    savebatch = [app.data.time(app.indx.save:end),app.data.temp(app.indx.save:end,:),app.data.pos(app.indx.save:end,:),...
                        app.data.posVolts(app.indx.save:end,:),app.data.sgtime(app.indx.save:end),app.data.sg(app.indx.save:end,:)];
                    for i = 1:length(app.data.time(app.indx.save:end))
                        fprintf(app.FID,app.saveForm,savebatch(i,:));
                    end
                    app.indx.save = length(app.data.time)+1;
                end
                %Close out file
                fclose('all');
                
                
                %Reset GUI and System
                app.ArmDataAcquisitionSwitch.Value = '';
                app.ArmDataAcquisitionSwitch.Enable = 'On';
                app.ArmDataAcquisitionSwitch.FontWeight = 'normal';
                app.ArmDataAcquisitionSwitch.FontColor = [0,0,0];
                app.FilenameEditField.Enable = 'On';
                app.ProjectFolderEditField.Enable = 'On';
                app.BeginLoggingDataButton.Enable = 'Off';
                app.BeginLoggingDataButton.Value = false;
                app.EndLoggingDataButton.Enable = 'Off';
                app.EndLoggingDataButton.Value = false;
                app.DAQRateHzEditField.Enable = 'On';
                app.PlotRateHzEditField.Enable = 'On';
                
                %Clear out, re-delcare matrices and indices
                app.data.time = [];
                app.data.temp = [];
                app.data.pos = [];
                app.data.posVolts = [];
                app.data.sgtime0 = [];
                app.data.sg0 = [];
                app.data.sgtime = [];
                app.data.sg = [];
                app.indx.sg = 1;
                app.indx.save = 1;
                
                
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.mainPlot = uiaxes(app.UIFigure);
            xlabel(app.mainPlot, 'Time (s)')
            ylabel(app.mainPlot, 'Voltage')
            app.FileSavingParametersPanel = uipanel(app.UIFigure);
            app.FileSavingParametersPanel.Title = 'File Saving Parameters';
            app.FilenameEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.ProjectFolderEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.ProjectFolderEditFieldLabel.Text = 'Project Folder';
            app.ProjectFolderEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.FolderButton = uibutton(app.FileSavingParametersPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelect, true);
            app.FolderButton.Text = '...';
            app.FolderNote = uilabel(app.FileSavingParametersPanel);
            app.FolderNote.Text = 'Note: Project Folder must be selected to start acquiring data!';
            app.DAQLoggingControlsPanel = uipanel(app.UIFigure);
            app.DAQLoggingControlsPanel.Title = 'DAQ/Logging Controls';
            app.BeginLoggingDataButton = uibutton(app.DAQLoggingControlsPanel, 'state');
            app.BeginLoggingDataButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingSession, true);
            app.BeginLoggingDataButton.Enable = 'off';
            app.BeginLoggingDataButton.Text = 'Begin Logging Data';
            app.ArmDataAcquisitionSwitchLabel = uilabel(app.DAQLoggingControlsPanel);
            app.ArmDataAcquisitionSwitchLabel.Text = 'Arm Data Acquisition';
            app.ArmDataAcquisitionSwitch = uiswitch(app.DAQLoggingControlsPanel, 'slider');
            app.ArmDataAcquisitionSwitch.Items = {'', 'Armed'};
            app.ArmDataAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmDataAcquisitionSwitchValueChanged, true);
            app.ArmDataAcquisitionSwitch.Enable = 'off';
            app.ArmDataAcquisitionSwitch.Value = '';
            app.EndLoggingDataButton = uibutton(app.DAQLoggingControlsPanel, 'state');
            app.EndLoggingDataButton.ValueChangedFcn = createCallbackFcn(app, @EndLoggingDataButtonValueChanged, true);
            app.EndLoggingDataButton.Enable = 'off';
            app.EndLoggingDataButton.Visible = 'off';
            app.EndLoggingDataButton.Text = 'End Logging Data';
            app.RateControlsPanel = uipanel(app.UIFigure);
            app.RateControlsPanel.Title = 'Rate Controls';
            app.DAQRateHzEditFieldLabel = uilabel(app.RateControlsPanel);
            app.DAQRateHzEditFieldLabel.Text = 'DAQ Rate (Hz)';
            app.DAQRateHzEditField = uieditfield(app.RateControlsPanel, 'numeric');
            app.DAQRateHzEditField.Value = 2;
            app.PlotRateHzEditFieldLabel = uilabel(app.RateControlsPanel);
            app.PlotRateHzEditFieldLabel.Text = 'Plot Rate (Hz)';
            app.PlotRateHzEditField = uieditfield(app.RateControlsPanel, 'numeric');
            app.PlotRateHzEditField.Value = 1;
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.chanName_2 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_2.Value = 'ch2';
            app.chanName_3 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_3.Value = 'ch3';
            app.chanName_4 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_4.Value = 'ch4';
            app.chanName_5 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_5.Value = 'ch5';
            app.chanName_6 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_6.Value = 'ch6';
            app.chanName_7 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_7.Value = 'ch7';
            app.chanName_8 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_8.Value = 'ch8';
            app.ChannelNameLabel = uilabel(app.ChannelsPanel);
            app.ChannelNameLabel.Text = 'Channel Name';
            app.EnableLabel = uilabel(app.ChannelsPanel);
            app.EnableLabel.Text = 'Enable';
            app.chanOn_1 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_1.Items = {'', ''};
            app.chanOn_1.Value = '';
            app.chanOn_2 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_2.Items = {'', ''};
            app.chanOn_2.Value = '';
            app.chanOn_3 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_3.Items = {'', ''};
            app.chanOn_3.Value = '';
            app.chanOn_4 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_4.Items = {'', ''};
            app.chanOn_4.Value = '';
            app.chanOn_5 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_5.Items = {'', ''};
            app.chanOn_5.Value = '';
            app.chanOn_6 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_6.Items = {'', ''};
            app.chanOn_6.Value = '';
            app.chanOn_7 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_7.Items = {'', ''};
            app.chanOn_7.Value = '';
            app.chanOn_8 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_8.Items = {'', ''};
            app.chanOn_8.Value = '';
            app.chanName_1 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_1.Value = 'ch1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end